classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElectriSimUIFigure  matlab.ui.Figure
        MainGrid            matlab.ui.container.GridLayout
        BodyGrid            matlab.ui.container.GridLayout
        RightPanel          matlab.ui.container.Panel
        rightGrid           matlab.ui.container.GridLayout
        RightTabs           matlab.ui.container.TabGroup
        ParamTab            matlab.ui.container.Tab
        KnowledgeTab        matlab.ui.container.Tab
        LogTab              matlab.ui.container.Tab
        LogGrid             matlab.ui.container.GridLayout
        LogTextArea         matlab.ui.control.TextArea
        CenterTabs          matlab.ui.container.TabGroup
        SceneTab            matlab.ui.container.Tab
        sceneGrid           matlab.ui.container.GridLayout
        SceneAxes           matlab.ui.control.UIAxes
        PlotsTab            matlab.ui.container.Tab
        plotsGrid           matlab.ui.container.GridLayout
        PlotsAxes           matlab.ui.control.UIAxes
        LeftPanel           matlab.ui.container.Panel
        TemplateGrid        matlab.ui.container.GridLayout
        TemplateTree        matlab.ui.container.Tree
        TopBarGrid          matlab.ui.container.GridLayout
        LogButton           matlab.ui.control.Button
        HelpButton          matlab.ui.control.Button
        ModeSwitch          matlab.ui.control.Switch
        ModeLabel           matlab.ui.control.Label
        ResetButton         matlab.ui.control.Button
        PauseButton         matlab.ui.control.Button
        PlayButton          matlab.ui.control.Button
        SpeedRow            matlab.ui.container.GridLayout
        SpeedLabel          matlab.ui.control.Label
        SpeedSlider         matlab.ui.control.Slider
        SpeedPlusButton     matlab.ui.control.Button
        SpeedMinusButton    matlab.ui.control.Button
        SpeedValueField     matlab.ui.control.NumericEditField
        SpeedValueLabel     matlab.ui.control.Label
    end


    properties (Access = private)
        ParamChangedListener = event.listener.empty
    end

    properties (Access = public)
        % 控制层/界面层/引擎层共享的运行时状态
        CurrentTemplateId   (1,1) string = "M1"
        CurrentSchemaKey    (1,1) string = "particle"
        CurrentEngineKey    (1,1) string = "particle"
        Params              (1,1) struct = struct()
        State               (1,1) struct = struct()

        % 参数组件容器与当前参数组件实例
        ParamHostGrid
        ParamComponent
    end

    methods (Access = private)

        function initializeRuntimeWiring(app)
            %INITIALIZERUNTIMEWIRING  构建 M1 最小闭环接线
            app.buildTemplateTree();
            app.ensureParamComponent();
            app.bindUiCallbacks();
            boot.startup(app);
            app.syncSpeedWidgets(app.getSpeedScaleFromParams());
            app.selectTemplateNode(app.CurrentTemplateId);
        end

        function buildTemplateTree(app)
            %BUILDTEMPLATETREE  根据 templates.registry() 构建模板树节点
            if ~isgraphics(app.TemplateTree)
                return;
            end

            delete(app.TemplateTree.Children);
            list = templates.registry();
            if isempty(list)
                return;
            end

            groups = unique(string({list.group}), 'stable');
            for ig = 1:numel(groups)
                g = groups(ig);
                parentNode = uitreenode(app.TemplateTree, ...
                    'Text', char(g), ...
                    'NodeData', "");
                idx = find(string({list.group}) == g);
                for k = idx
                    tpl = list(k);
                    uitreenode(parentNode, ...
                        'Text', char(tpl.title), ...
                        'NodeData', string(tpl.id));
                end
            end
            expand(app.TemplateTree);
        end

        function ensureParamComponent(app)
            %ENSUREPARAMCOMPONENT  确保 M1 参数组件已挂载
            if isempty(app.ParamHostGrid) || ~isgraphics(app.ParamHostGrid)
                app.ParamHostGrid = uigridlayout(app.ParamTab);
                app.ParamHostGrid.ColumnWidth = {'1x'};
                app.ParamHostGrid.RowHeight = {'1x'};
                app.ParamHostGrid.Padding = [6 6 6 6];
            end

            if ~isempty(app.ParamComponent) && isa(app.ParamComponent, 'handle') && isvalid(app.ParamComponent)
                return;
            end

            if exist('M1_for_test', 'class') == 8
                app.ParamComponent = M1_for_test(app.ParamHostGrid);
                app.ParamComponent.Layout.Row = 1;
                app.ParamComponent.Layout.Column = 1;
            else
                app.ParamComponent = [];
                hint = uilabel(app.ParamHostGrid);
                hint.Layout.Row = 1;
                hint.Layout.Column = 1;
                hint.WordWrap = 'on';
                hint.Text = '未找到 m/M1_for_test.m（参数组件未加载）';
            end

            if ~isempty(app.ParamChangedListener) && isvalid(app.ParamChangedListener)
                delete(app.ParamChangedListener);
            end
            app.ParamChangedListener = event.listener.empty;
            if ~isempty(app.ParamComponent)
                try
                    app.ParamChangedListener = addlistener( ...
                        app.ParamComponent, ...
                        'PayloadChanged', ...
                        @(~,~)control.onParamsChanged(app));
                catch
                    app.ParamChangedListener = event.listener.empty;
                end
            end
        end

        function bindUiCallbacks(app)
            %BINDUICALLBACKS  绑定顶层 UI 回调到控制层
            app.TemplateTree.SelectionChangedFcn = @(~, evt)control.onTemplateChanged(app, evt);
            app.PlayButton.ButtonPushedFcn = @(~,~)control.onPlay(app);
            app.ResetButton.ButtonPushedFcn = @(~,~)control.onReset(app);
            app.PauseButton.ButtonPushedFcn = @(~,~)[];

            app.SpeedSlider.ValueChangedFcn = @(~,~)app.onSpeedSliderChanged();
            app.SpeedValueField.ValueChangedFcn = @(~,~)app.onSpeedFieldChanged();
            app.SpeedMinusButton.ButtonPushedFcn = @(~,~)app.bumpSpeed(-0.5);
            app.SpeedPlusButton.ButtonPushedFcn = @(~,~)app.bumpSpeed(0.5);
        end

        function onSpeedSliderChanged(app)
            app.syncSpeedWidgets(app.SpeedSlider.Value);
        end

        function onSpeedFieldChanged(app)
            app.syncSpeedWidgets(app.SpeedValueField.Value);
        end

        function bumpSpeed(app, delta)
            app.syncSpeedWidgets(app.SpeedSlider.Value + delta);
        end

        function syncSpeedWidgets(app, speedValue)
            %SYNCSPEEDWIDGETS  保持速度控件与参数结构同步
            val = max(0.25, min(4.0, double(speedValue)));

            if isgraphics(app.SpeedSlider)
                app.SpeedSlider.Value = val;
            end
            if isgraphics(app.SpeedValueField)
                app.SpeedValueField.Value = val;
            end
            if isgraphics(app.SpeedValueLabel)
                app.SpeedValueLabel.Text = sprintf('%.2fx', val);
            end

            if isstruct(app.Params)
                app.Params.speedScale = val;
            end
        end

        function speedValue = getSpeedScaleFromParams(app)
            speedValue = 1.0;
            if isstruct(app.Params) && isfield(app.Params, 'speedScale')
                speedValue = app.Params.speedScale;
            end
        end

        function selectTemplateNode(app, tplId)
            %SELECTTEMPLATENODE  按模板 id 选中模板树节点
            if ~isgraphics(app.TemplateTree)
                return;
            end

            target = upper(strtrim(string(tplId)));
            allNodes = app.TemplateTree.Children;
            for i = 1:numel(allNodes)
                hit = app.findNodeById(allNodes(i), target);
                if ~isempty(hit)
                    app.TemplateTree.SelectedNodes = hit;
                    return;
                end
            end
        end

        function hit = findNodeById(app, node, targetId)
            %#ok<INUSL>
            hit = [];
            if isprop(node, 'NodeData')
                nodeId = upper(strtrim(string(node.NodeData)));
                if nodeId == targetId
                    hit = node;
                    return;
                end
            end

            children = node.Children;
            for i = 1:numel(children)
                hit = app.findNodeById(children(i), targetId);
                if ~isempty(hit)
                    return;
                end
            end
        end

        function ensureDevPaths(~)
            %ENSUREDEVPATHS  确保 apps/src/m 目录已加入 MATLAB 路径
            appPath = mfilename('fullpath');
            root = fileparts(fileparts(appPath));
            srcDir = fullfile(root, 'src');
            mDir = fullfile(root, 'm');

            if isfolder(srcDir) && isempty(which('boot.startup'))
                addpath(srcDir);
            end
            if isfolder(mDir) && isempty(which('M1_for_test'))
                addpath(mDir);
            end
        end
    end


    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElectriSimUIFigure = uifigure('Visible', 'off');
            app.ElectriSimUIFigure.Name = 'ElectriSim';
            app.MainGrid = uigridlayout(app.ElectriSimUIFigure);
            app.SpeedRow = uigridlayout(app.MainGrid);
            app.SpeedValueLabel = uilabel(app.SpeedRow);
            app.SpeedValueLabel.Text = '1.00×';
            app.SpeedValueField = uieditfield(app.SpeedRow, 'numeric');
            app.SpeedValueField.Limits = [0.25 4];
            app.SpeedValueField.Value = 1;
            app.SpeedMinusButton = uibutton(app.SpeedRow, 'push');
            app.SpeedMinusButton.Text = '-0.5';
            app.SpeedPlusButton = uibutton(app.SpeedRow, 'push');
            app.SpeedPlusButton.Text = '+0.5';
            app.SpeedSlider = uislider(app.SpeedRow);
            app.SpeedSlider.Limits = [0.25 4];
            app.SpeedSlider.MajorTicks = [];
            app.SpeedSlider.MajorTickLabels = {''};
            app.SpeedSlider.MinorTicks = 0;
            app.SpeedSlider.Value = 1;
            app.SpeedLabel = uilabel(app.SpeedRow);
            app.SpeedLabel.Text = '速度：';
            app.TopBarGrid = uigridlayout(app.MainGrid);
            app.PlayButton = uibutton(app.TopBarGrid, 'push');
            app.PlayButton.Text = '运行';
            app.PauseButton = uibutton(app.TopBarGrid, 'push');
            app.PauseButton.Text = '暂停';
            app.ResetButton = uibutton(app.TopBarGrid, 'push');
            app.ResetButton.Text = '重置';
            app.ModeLabel = uilabel(app.TopBarGrid);
            app.ModeLabel.Text = '模式：';
            app.ModeSwitch = uiswitch(app.TopBarGrid, 'slider');
            app.ModeSwitch.Items = {'教学', '创造'};
            app.ModeSwitch.Value = '教学';
            app.HelpButton = uibutton(app.TopBarGrid, 'push');
            app.HelpButton.Text = '帮助';
            app.LogButton = uibutton(app.TopBarGrid, 'push');
            app.LogButton.Text = '导出日志';
            app.BodyGrid = uigridlayout(app.MainGrid);
            app.LeftPanel = uipanel(app.BodyGrid);
            app.LeftPanel.Title = '教学目录';
            app.TemplateGrid = uigridlayout(app.LeftPanel);
            app.TemplateTree = uitree(app.TemplateGrid);
            app.CenterTabs = uitabgroup(app.BodyGrid);
            app.SceneTab = uitab(app.CenterTabs);
            app.SceneTab.Title = '场景';
            app.sceneGrid = uigridlayout(app.SceneTab);
            app.SceneAxes = uiaxes(app.sceneGrid);
            title(app.SceneAxes, '粒子运动场景')
            xlabel(app.SceneAxes, 'x (m)')
            ylabel(app.SceneAxes, 'y (m)')
            app.PlotsTab = uitab(app.CenterTabs);
            app.PlotsTab.Title = '曲线';
            app.plotsGrid = uigridlayout(app.PlotsTab);
            app.PlotsAxes = uiaxes(app.plotsGrid);
            title(app.PlotsAxes, '粒子运动场景')
            xlabel(app.PlotsAxes, 'x (m)')
            ylabel(app.PlotsAxes, 'y (m)')
            app.RightPanel = uipanel(app.BodyGrid);
            app.RightPanel.Title = '控制台';
            app.rightGrid = uigridlayout(app.RightPanel);
            app.RightTabs = uitabgroup(app.rightGrid);
            app.ParamTab = uitab(app.RightTabs);
            app.ParamTab.Title = '参数';
            app.KnowledgeTab = uitab(app.RightTabs);
            app.KnowledgeTab.Title = '知识点';
            app.LogTab = uitab(app.RightTabs);
            app.LogTab.Title = '日志';
            app.LogGrid = uigridlayout(app.LogTab);
            app.LogTextArea = uitextarea(app.LogGrid);
            % Show the figure after all components are created
            app.ElectriSimUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElectriSimUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElectriSimUIFigure)
        end
    end
end